classdef combinebestbinsGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UITable                        matlab.ui.control.Table
        CurrentBinsLabel               matlab.ui.control.Label
        CurrentlyLoadedBESTsetEditFieldLabel  matlab.ui.control.Label
        CurrentlyLoadedBESTsetEditField  matlab.ui.control.EditField
        CreatecombinedbinsmodulePanel  matlab.ui.container.Panel
        bins_tocombine                 matlab.ui.control.EditField
        Instructlabel                  matlab.ui.control.Label
        Forexample12or15Label          matlab.ui.control.Label
        EnternewbinlabelLabel          matlab.ui.control.Label
        label_newbin                   matlab.ui.control.EditField
        ApplyButton                    matlab.ui.control.Button
        UITable_2                      matlab.ui.control.Table
        BinsofnewBESTsetLabel          matlab.ui.control.Label
        DoneButton                     matlab.ui.control.Button
        RemoveselectedrowButton        matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
    end

    
    properties (Access = public)
        FinishButton % Description
    end
    
    properties (Access = private)
        selected_cells % Description
        BEST % Description
        finalbins % Description
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            %inputs
            ALLBEST = varargin{1};
            currset = varargin{2};
            bins_to_combine = varargin{3}{1}; 
            bin_labels = varargin{3}{2};
            
            
            %% Obtain bins
            %% only BESTset at a time
            BEST = ALLBEST(currset); 
            n_bins = numel(BEST.binwise_data);
            
            % Load BESTname
            app.CurrentlyLoadedBESTsetEditField.Value = BEST.bestname; 
            
            %create original datatable
            og_nbins = cellstr(string(1:n_bins)) ; 
            og_data = {BEST.bindesc{:}}; 
            og_ntrials = cellstr(string(BEST.n_trials_per_bin)); 
            app.UITable.Data = [og_nbins',og_data',og_ntrials'];
            
            %create new "combined bins" datatable
            app.UITable_2.Data = [og_nbins',og_data',og_ntrials'];


%             valid_eeg = zeros(n_bins,1); %index
%             valid_eeg_logical = zeros(n_bins,1); %for GUI             
%          
%             for i = 1:n_eeg
%                     
%                 if ~isempty(ALLEEG(i).epoch)
%                     
%                     valid_eeg(i) = i;
%                     valid_eeg_logical(i) = 1; 
%                     
%                 else
%                     valid_eeg_logical(i) = 0; 
%                     
%                 end
%                 
%             end
%             
%             
%             %add to table component (list ACTIVE ONLY)
%             EEGnames = {ALLEEG(currset).setname};
%             
%             %cnames = table(EEGnames, valid_eeg_logical,bin_check);
%             app.UITable2.Data = EEGnames; % OneSet


            %paint GUI
            painterplabapp(app);
            
            
            %main binstructure
            app.finalbins = {}; 


            %to close out gui
            ready = 0;
            if ready == 1  
                app.DoneButton = 1; 
            end
            
            
            %save into app
            app.BEST = BEST; 
            
            
        end

        % Button pushed function: RemoveselectedrowButton
        function RemoveselectedrowButtonPushed(app, event)
            
             curr_rows = app.UITable_2.Data;
            
            if ~isempty(app.selected_cells)
                NumSelected = size(app.selected_cells, 1); 
                
                for i = 1:NumSelected
                    Rows(i) = app.selected_cells(i,1);             
                end
                
                unique_rows = flip(unique(Rows)); 
                
                for i = 1:length(unique_rows)
                    curr_rows(unique_rows(i),:) = []; 
                end
                
                %update the bin_index number 
                new_Nrows = size(curr_rows,1);
                curr_rows(:,1) = cellstr(string(1:new_Nrows))'; 
                
                app.UITable_2.Data = curr_rows; 
                app.selected_cells = []; 
                
            else
                %delete last row
                curr_rows(length(curr_rows),:) = [];
                %update the bin_index number 
                new_Nrows = length(curr_rows);
                curr_rows(:,1) = cellstr(string(1:new_Nrows)); 
                
                app.UITable_2.Data = curr_rows; 
                app.selected_cells = []; 


            end 


        end

        % Cell selection callback: UITable_2
        function UITable_2CellSelection(app, event)
            indices = event.Indices;
            
            app.selected_cells = event.Indices; 
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
            %get data from table one
            curr_data = app.UITable.Data;
            app.UITable_2.Data = curr_data; %reset
            
        end

        % Value changed function: bins_tocombine
        function bins_tocombineValueChanged(app, event)
            value = app.bins_tocombine.Value;
            
            try 
                bin_num = str2num(value);
            catch
                msgboxText =  'Input invalid: please input bin index numbers only!';
                title = 'ERPLAB: error at pop_combineBESTbins()';
                errorfound(msgboxText, title);
                return
            end
            
            
        end

        % Value changed function: label_newbin
        function label_newbinValueChanged(app, event)
            value = app.label_newbin.Value;
            
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
               
            curr_rows = app.UITable_2.Data; % current data
            
            bin_inds = str2num(app.bins_tocombine.Value);
            bin_label = app.label_newbin.Value;; 
            
            
            %update the bin_index number
            new_Nrows = size(curr_rows,1) + 1; 
            curr_rows(new_Nrows,1) = cellstr(string(new_Nrows));
            
            curr_rows(new_Nrows,2) = {bin_label};
            
            %combine data
            combined_Ntrials = 0; 
            for i = 1:length(bin_inds)
                combined_Ntrials = combined_Ntrials + str2num(curr_rows{bin_inds(i),3}); 
            end
            
            curr_rows(new_Nrows,3) = {num2str(combined_Ntrials)};
            
            app.UITable_2.Data = curr_rows;
            
            
            %save selections 
    %%        app.finalbins = ret; 


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Bin Index'; 'Bin Label'; 'N (per Bin)'};
            app.UITable.RowName = {};
            app.CurrentBinsLabel = uilabel(app.UIFigure);
            app.CurrentBinsLabel.Text = 'Current Bins ';
            app.CurrentlyLoadedBESTsetEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentlyLoadedBESTsetEditFieldLabel.Text = 'Currently Loaded BESTset';
            app.CurrentlyLoadedBESTsetEditField = uieditfield(app.UIFigure, 'text');
            app.CurrentlyLoadedBESTsetEditField.Tag = 'bestset_name';
            app.CreatecombinedbinsmodulePanel = uipanel(app.UIFigure);
            app.CreatecombinedbinsmodulePanel.Title = 'Create combined bins module';
            app.bins_tocombine = uieditfield(app.CreatecombinedbinsmodulePanel, 'text');
            app.bins_tocombine.ValueChangedFcn = createCallbackFcn(app, @bins_tocombineValueChanged, true);
            app.bins_tocombine.Tag = 'bin_combo';
            app.Instructlabel = uilabel(app.CreatecombinedbinsmodulePanel);
            app.Instructlabel.Text = 'Enter array of bins to combine using Bin Index values.';
            app.Forexample12or15Label = uilabel(app.CreatecombinedbinsmodulePanel);
            app.Forexample12or15Label.Text = 'For example: [1,2] or [1:5]';
            app.EnternewbinlabelLabel = uilabel(app.CreatecombinedbinsmodulePanel);
            app.EnternewbinlabelLabel.Text = 'Enter new bin label: ';
            app.label_newbin = uieditfield(app.CreatecombinedbinsmodulePanel, 'text');
            app.label_newbin.ValueChangedFcn = createCallbackFcn(app, @label_newbinValueChanged, true);
            app.ApplyButton = uibutton(app.CreatecombinedbinsmodulePanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.UITable_2 = uitable(app.UIFigure);
            app.UITable_2.ColumnName = {'Bin Index'; 'Bin Label'; 'N (per Bin)'};
            app.UITable_2.RowName = {};
            app.UITable_2.CellSelectionCallback = createCallbackFcn(app, @UITable_2CellSelection, true);
            app.BinsofnewBESTsetLabel = uilabel(app.UIFigure);
            app.BinsofnewBESTsetLabel.Text = 'Bins of new BESTset';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.Text = 'Done';
            app.RemoveselectedrowButton = uibutton(app.UIFigure, 'push');
            app.RemoveselectedrowButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveselectedrowButtonPushed, true);
            app.RemoveselectedrowButton.Text = 'Remove selected row';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = combinebestbinsGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end