classdef EStudio_pos_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        OkayButton    matlab.ui.control.Button
        CancelButton  matlab.ui.control.Button
        PositionforEStudioleftbottomwidthheightinmsLabel  matlab.ui.control.Label
        Edit_pos      matlab.ui.control.EditField
    end


    properties (Access = public)
        output % Description
        Finishbutton % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            painterplabapp(app);
            setfonterplab(app);
            % Name & version
            %
            version = geterplabversion;
            set(app.UIFigure,'Name', ['ERPLAB ' version '   -   EStudio Position Editor GUI']);
            try
                Pos = varargin{1};
            catch
               Pos = [0.01,0.01,75,75];
            end
            if isempty(Pos)|| ~isnumeric(Pos) || numel(Pos)~=4
               Pos = [0.01,0.01,75,75]; 
            end
            app.Edit_pos.Value = num2str(roundn(Pos,-2));
            
        end

        % Value changed function: Edit_pos
        function Postion_edit(app, event)
            Pos = str2num(app.Edit_pos.Value);
            if isempty(Pos) || numel(Pos)~=4
                app.Edit_pos.Value = '';
                msgboxText = ['EStudio position must have four numbers.'];
                title = 'EStudio_pos_gui()';
                errorfound(sprintf(msgboxText), title);
            end
             app.Edit_pos.Value = num2str(roundn(Pos,-2));
        end

        % Button pushed function: CancelButton
        function Cancel(app, event)
            app.output =[];
            app.Finishbutton = 1;
        end

        % Button pushed function: OkayButton
        function Okay(app, event)
            Pos = str2num(app.Edit_pos.Value);
            if isempty(Pos) || numel(Pos)~=4
                Pos = [];
            end
            app.output =roundn(Pos,-2);
            app.Finishbutton = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Edit_pos = uieditfield(app.UIFigure, 'text');
            app.Edit_pos.ValueChangedFcn = createCallbackFcn(app, @Postion_edit, true);
            app.PositionforEStudioleftbottomwidthheightinmsLabel = uilabel(app.UIFigure);
            app.PositionforEStudioleftbottomwidthheightinmsLabel.Text = 'Position for EStudio [left, bottom,width, height] in ms';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @Cancel, true);
            app.CancelButton.Text = 'Cancel';
            app.OkayButton = uibutton(app.UIFigure, 'push');
            app.OkayButton.ButtonPushedFcn = createCallbackFcn(app, @Okay, true);
            app.OkayButton.Text = 'Okay';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EStudio_pos_gui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end